classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        TabGroup                    matlab.ui.container.TabGroup
        MainviewTab                 matlab.ui.container.Tab
        GridLayout2                 matlab.ui.container.GridLayout
        GridLayout3                 matlab.ui.container.GridLayout
        panel_map                   matlab.ui.container.Panel
        Panel                       matlab.ui.container.Panel
        GridLayout4                 matlab.ui.container.GridLayout
        GridLayout5                 matlab.ui.container.GridLayout
        GridLayout9                 matlab.ui.container.GridLayout
        GridLayout10                matlab.ui.container.GridLayout
        GridLayout11_2              matlab.ui.container.GridLayout
        GridLayout17_2              matlab.ui.container.GridLayout
        GridLayout18_2              matlab.ui.container.GridLayout
        target_new_data             matlab.ui.control.Lamp
        NewdataLamp_2Label          matlab.ui.control.Label
        target_loc_source           matlab.ui.control.Switch
        GridLayout12_2              matlab.ui.container.GridLayout
        GridLayout16_2              matlab.ui.container.GridLayout
        target_StepsizeButtonGroup  matlab.ui.container.ButtonGroup
        target_step_1000            matlab.ui.control.RadioButton
        target_step_100             matlab.ui.control.RadioButton
        target_step_10              matlab.ui.control.RadioButton
        target_step_1               matlab.ui.control.RadioButton
        GridLayout13_2              matlab.ui.container.GridLayout
        GridLayout15_2              matlab.ui.container.GridLayout
        target_position_right       matlab.ui.control.Button
        target_position_down        matlab.ui.control.Button
        target_position_left        matlab.ui.control.Button
        target_position_up          matlab.ui.control.Button
        GridLayout14_4              matlab.ui.container.GridLayout
        target_LONGEditField        matlab.ui.control.NumericEditField
        LONGEditField_2Label        matlab.ui.control.Label
        GridLayout14_3              matlab.ui.container.GridLayout
        target_LATEditField         matlab.ui.control.NumericEditField
        LATEditField_2Label         matlab.ui.control.Label
        GridLayout11                matlab.ui.container.GridLayout
        GridLayout17                matlab.ui.container.GridLayout
        GridLayout18                matlab.ui.container.GridLayout
        base_new_data_lamp          matlab.ui.control.Lamp
        NewdataLampLabel            matlab.ui.control.Label
        base_loc_source             matlab.ui.control.Switch
        GridLayout12                matlab.ui.container.GridLayout
        GridLayout16                matlab.ui.container.GridLayout
        base_StepsizeButtonGroup    matlab.ui.container.ButtonGroup
        base_step_1000              matlab.ui.control.RadioButton
        base_step_100               matlab.ui.control.RadioButton
        base_step_10                matlab.ui.control.RadioButton
        base_step_1                 matlab.ui.control.RadioButton
        GridLayout13                matlab.ui.container.GridLayout
        GridLayout15                matlab.ui.container.GridLayout
        base_position_right         matlab.ui.control.Button
        base_position_down          matlab.ui.control.Button
        base_position_left          matlab.ui.control.Button
        base_position_up            matlab.ui.control.Button
        GridLayout14_2              matlab.ui.container.GridLayout
        LONGEditField               matlab.ui.control.NumericEditField
        LONGEditFieldLabel          matlab.ui.control.Label
        GridLayout14                matlab.ui.container.GridLayout
        LATEditField                matlab.ui.control.NumericEditField
        LATEditFieldLabel           matlab.ui.control.Label
        TargetLabel                 matlab.ui.control.Label
        BaseLabel                   matlab.ui.control.Label
        LocationsourcesLabel        matlab.ui.control.Label
        GridLayout6                 matlab.ui.container.GridLayout
        GridLayout8                 matlab.ui.container.GridLayout
        DisconnectButton            matlab.ui.control.Button
        ConnectButton               matlab.ui.control.Button
        RefreshButton               matlab.ui.control.Button
        Lamp                        matlab.ui.control.Lamp
        GridLayout7                 matlab.ui.container.GridLayout
        SelectCOMportDropDown       matlab.ui.control.DropDown
        SelectCOMportDropDownLabel  matlab.ui.control.Label
        ManualcontrolTab            matlab.ui.container.Tab
        SettingsTab                 matlab.ui.container.Tab
    end

    
    methods (Access = private)
        
        function results = func(app)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            geoAx = geoaxes('Parent', app.panel_map,'Position', [0.05 0.05 0.9 0.9]);
            geobasemap(geoAx, "streets");
            hold(geoAx, 'on');
            while(1)
                app.Lamp.Color = 'red';
                pause(1)
                app.Lamp.Color = 'green';
                pause(1)
            end
        end

        % Value changed function: base_loc_source
        function base_location_source_fcn(app, event)
            value = app.base_loc_source.Value;
            switch value
                case "GPS"
                    app.LONGEditField.Enable = "off";
                    app.LATEditField.Enable = "off";

                    app.base_position_left.Enable = "off";
                    app.base_position_right.Enable = "off";
                    app.base_position_up.Enable = "off";
                    app.base_position_down.Enable = "off";

                    app.base_StepsizeButtonGroup.Enable = "off";
                case "User"
                    app.LONGEditField.Enable = "on";
                    app.LATEditField.Enable = "on";

                    app.base_position_left.Enable = "on";
                    app.base_position_right.Enable = "on";
                    app.base_position_up.Enable = "on";
                    app.base_position_down.Enable = "on";

                    app.base_StepsizeButtonGroup.Enable = "on";
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            
        end

        % Value changed function: target_loc_source
        function target_loc_sourceValueChanged(app, event)
            value = app.target_loc_source.Value;
            switch value
                case "GPS"
                    app.target_LONGEditFieapp.panel_mapld.Enable = "off";
                    app.target_LATEditField.Enable = "off";

                    app.target_position_left.Enable = "off";
                    app.target_position_right.Enable = "off";
                    app.target_position_up.Enable = "off";
                    app.target_position_down.Enable = "off";

                    app.target_StepsizeButtonGroup.Enable = "off";
                case "User"
                    app.target_LONGEditField.Enable = "on";
                    app.target_LATEditField.Enable = "on";

                    app.target_position_left.Enable = "on";
                    app.target_position_right.Enable = "on";
                    app.target_position_up.Enable = "on";
                    app.target_position_down.Enable = "on";

                    app.target_StepsizeButtonGroup.Enable = "on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.MainviewTab = uitab(app.TabGroup);
            app.MainviewTab.Title = 'Main view';
            app.GridLayout2 = uigridlayout(app.MainviewTab);
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout4 = uigridlayout(app.Panel);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.GridLayout6 = uigridlayout(app.GridLayout5);
            app.GridLayout7 = uigridlayout(app.GridLayout6);
            app.SelectCOMportDropDownLabel = uilabel(app.GridLayout7);
            app.SelectCOMportDropDownLabel.Text = 'Select COM port';
            app.SelectCOMportDropDown = uidropdown(app.GridLayout7);
            app.GridLayout8 = uigridlayout(app.GridLayout6);
            app.Lamp = uilamp(app.GridLayout8);
            app.RefreshButton = uibutton(app.GridLayout8, 'push');
            app.RefreshButton.Text = 'Refresh';
            app.ConnectButton = uibutton(app.GridLayout8, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.GridLayout8, 'push');
            app.DisconnectButton.Text = 'Disconnect';
            app.GridLayout9 = uigridlayout(app.GridLayout5);
            app.GridLayout10 = uigridlayout(app.GridLayout9);
            app.LocationsourcesLabel = uilabel(app.GridLayout10);
            app.LocationsourcesLabel.Text = 'Location sources';
            app.BaseLabel = uilabel(app.GridLayout10);
            app.BaseLabel.Text = 'Base';
            app.TargetLabel = uilabel(app.GridLayout10);
            app.TargetLabel.Text = 'Target';
            app.GridLayout11 = uigridlayout(app.GridLayout10);
            app.GridLayout12 = uigridlayout(app.GridLayout11);
            app.GridLayout13 = uigridlayout(app.GridLayout12);
            app.GridLayout14 = uigridlayout(app.GridLayout13);
            app.LATEditFieldLabel = uilabel(app.GridLayout14);
            app.LATEditFieldLabel.Enable = 'off';
            app.LATEditFieldLabel.Text = 'LAT';
            app.LATEditField = uieditfield(app.GridLayout14, 'numeric');
            app.LATEditField.ValueDisplayFormat = '%2.7f N';
            app.LATEditField.Editable = 'off';
            app.LATEditField.Enable = 'off';
            app.GridLayout14_2 = uigridlayout(app.GridLayout13);
            app.LONGEditFieldLabel = uilabel(app.GridLayout14_2);
            app.LONGEditFieldLabel.Enable = 'off';
            app.LONGEditFieldLabel.Text = 'LONG';
            app.LONGEditField = uieditfield(app.GridLayout14_2, 'numeric');
            app.LONGEditField.ValueDisplayFormat = '%2.7f E';
            app.LONGEditField.Enable = 'off';
            app.GridLayout15 = uigridlayout(app.GridLayout13);
            app.base_position_up = uibutton(app.GridLayout15, 'push');
            app.base_position_up.Enable = 'off';
            app.base_position_up.Text = '↑';
            app.base_position_left = uibutton(app.GridLayout15, 'push');
            app.base_position_left.Enable = 'off';
            app.base_position_left.Text = '←';
            app.base_position_down = uibutton(app.GridLayout15, 'push');
            app.base_position_down.Enable = 'off';
            app.base_position_down.Text = '↓';
            app.base_position_right = uibutton(app.GridLayout15, 'push');
            app.base_position_right.Enable = 'off';
            app.base_position_right.Text = '→';
            app.GridLayout16 = uigridlayout(app.GridLayout12);
            app.base_StepsizeButtonGroup = uibuttongroup(app.GridLayout16);
            app.base_StepsizeButtonGroup.Enable = 'off';
            app.base_StepsizeButtonGroup.Title = 'Step size';
            app.base_step_1 = uiradiobutton(app.base_StepsizeButtonGroup);
            app.base_step_1.Text = '1x';
            app.base_step_1.Value = true;
            app.base_step_10 = uiradiobutton(app.base_StepsizeButtonGroup);
            app.base_step_10.Text = '10x';
            app.base_step_100 = uiradiobutton(app.base_StepsizeButtonGroup);
            app.base_step_100.Text = '100x';
            app.base_step_1000 = uiradiobutton(app.base_StepsizeButtonGroup);
            app.base_step_1000.Text = '1000x';
            app.GridLayout17 = uigridlayout(app.GridLayout11);
            app.base_loc_source = uiswitch(app.GridLayout17, 'slider');
            app.base_loc_source.Items = {'GPS', 'User'};
            app.base_loc_source.ValueChangedFcn = createCallbackFcn(app, @base_location_source_fcn, true);
            app.base_loc_source.Value = 'GPS';
            app.GridLayout18 = uigridlayout(app.GridLayout17);
            app.NewdataLampLabel = uilabel(app.GridLayout18);
            app.NewdataLampLabel.Text = 'New data';
            app.base_new_data_lamp = uilamp(app.GridLayout18);
            app.GridLayout11_2 = uigridlayout(app.GridLayout10);
            app.GridLayout12_2 = uigridlayout(app.GridLayout11_2);
            app.GridLayout13_2 = uigridlayout(app.GridLayout12_2);
            app.GridLayout14_3 = uigridlayout(app.GridLayout13_2);
            app.LATEditField_2Label = uilabel(app.GridLayout14_3);
            app.LATEditField_2Label.Enable = 'off';
            app.LATEditField_2Label.Text = 'LAT';
            app.target_LATEditField = uieditfield(app.GridLayout14_3, 'numeric');
            app.target_LATEditField.ValueDisplayFormat = '%2.7f N';
            app.target_LATEditField.Enable = 'off';
            app.GridLayout14_4 = uigridlayout(app.GridLayout13_2);
            app.LONGEditField_2Label = uilabel(app.GridLayout14_4);
            app.LONGEditField_2Label.Enable = 'off';
            app.LONGEditField_2Label.Text = 'LONG';
            app.target_LONGEditField = uieditfield(app.GridLayout14_4, 'numeric');
            app.target_LONGEditField.ValueDisplayFormat = '%2.7f E';
            app.target_LONGEditField.Enable = 'off';
            app.GridLayout15_2 = uigridlayout(app.GridLayout13_2);
            app.target_position_up = uibutton(app.GridLayout15_2, 'push');
            app.target_position_up.Enable = 'off';
            app.target_position_up.Text = '↑';
            app.target_position_left = uibutton(app.GridLayout15_2, 'push');
            app.target_position_left.Enable = 'off';
            app.target_position_left.Text = '←';
            app.target_position_down = uibutton(app.GridLayout15_2, 'push');
            app.target_position_down.Enable = 'off';
            app.target_position_down.Text = '↓';
            app.target_position_right = uibutton(app.GridLayout15_2, 'push');
            app.target_position_right.Enable = 'off';
            app.target_position_right.Text = '→';
            app.GridLayout16_2 = uigridlayout(app.GridLayout12_2);
            app.target_StepsizeButtonGroup = uibuttongroup(app.GridLayout16_2);
            app.target_StepsizeButtonGroup.Enable = 'off';
            app.target_StepsizeButtonGroup.Title = 'Step size';
            app.target_step_1 = uiradiobutton(app.target_StepsizeButtonGroup);
            app.target_step_1.Text = '1x';
            app.target_step_1.Value = true;
            app.target_step_10 = uiradiobutton(app.target_StepsizeButtonGroup);
            app.target_step_10.Text = '10x';
            app.target_step_100 = uiradiobutton(app.target_StepsizeButtonGroup);
            app.target_step_100.Text = '100x';
            app.target_step_1000 = uiradiobutton(app.target_StepsizeButtonGroup);
            app.target_step_1000.Text = '1000x';
            app.GridLayout17_2 = uigridlayout(app.GridLayout11_2);
            app.target_loc_source = uiswitch(app.GridLayout17_2, 'slider');
            app.target_loc_source.Items = {'GPS', 'User'};
            app.target_loc_source.ValueChangedFcn = createCallbackFcn(app, @target_loc_sourceValueChanged, true);
            app.target_loc_source.Value = 'GPS';
            app.GridLayout18_2 = uigridlayout(app.GridLayout17_2);
            app.NewdataLamp_2Label = uilabel(app.GridLayout18_2);
            app.NewdataLamp_2Label.Text = 'New data';
            app.target_new_data = uilamp(app.GridLayout18_2);
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.panel_map = uipanel(app.GridLayout3);
            app.panel_map.Title = 'Panel';
            app.ManualcontrolTab = uitab(app.TabGroup);
            app.ManualcontrolTab.Title = 'Manual control';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end